classdef Elevator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Floor2Panel    matlab.ui.container.Panel
        floor2up       matlab.ui.control.Button
        floor2down     matlab.ui.control.Button
        floor2imgl     matlab.ui.control.Image
        floor2imgr     matlab.ui.control.Image
        Floor1Panel    matlab.ui.container.Panel
        floor1up       matlab.ui.control.Button
        floor1imgl     matlab.ui.control.Image
        floor1imgr     matlab.ui.control.Image
        Floor3Panel_2  matlab.ui.container.Panel
        floor3down     matlab.ui.control.Button
        floor3imgl     matlab.ui.control.Image
        floor3imgr     matlab.ui.control.Image
    end

    
    properties (Access = private)
         % Description
%         request %indicate which floor needs to go
    end
    
    properties (Access = public)
        dummy
        request % Description
        requestl
        requestr
        flagrunning
        testflag = 1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            panelapp1 = InsidePanel;
            panelapp2 = InsidePanel2;
            app.request(1)=0;
            app.request(2)=0;
            app.request(3)=0;
%             app.elevimgl.Position = [27 1 150 186];
%             app.elevimgr.Position = [417 1 150 186];
            app.dummy=elevatorfunc;
            app.dummy.app=app;
            app.dummy.panell=panelapp1;
            app.dummy.panell.outside = app;
            
            app.dummy.panelr=panelapp2;
            app.dummy.panelr.outside = app;
            panelapp1.dummy=app.dummy;
            panelapp2.dummy=app.dummy;
            app.requestl=panelapp1.requestl;
            app.requestr=panelapp2.requestr;
            app.flagrunning=0;


        end

        % Button pushed function: floor2up
        function floor2upPushed(app, event)
%             app.dummy.gotofloor2;
            app.request(2)=1;
%             if app.request(1)==0 && app.request(3)==0 && app.requestl(1)==0 && app.requestl(3)==0 && app.requestr(1)==0 && app.requestr(3)==0
%                 app.dummy.runelev;
%             end
            if app.flagrunning==0 && app.testflag ==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: floor3down
        function floor3downButtonPushed(app, event)
%             app.dummy.gotofloor3;
            app.request(3)=1;
            if app.flagrunning==0 && app.testflag ==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: floor2down
        function floor2downButtonPushed(app, event)
%             app.dummy.gotofloor2;
            app.request(2)=1;
            if app.flagrunning==0 && app.testflag ==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: floor1up
        function floor1upButtonPushed(app, event)
%             app.dummy.gotofloor1;
            app.request(1)=1;
            if app.flagrunning==0 && app.testflag ==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor2';
            app.floor2up = uibutton(app.Floor2Panel, 'push');
            app.floor2up.ButtonPushedFcn = createCallbackFcn(app, @floor2upPushed, true);
            app.floor2up.Text = '↑';
            app.floor2down = uibutton(app.Floor2Panel, 'push');
            app.floor2down.ButtonPushedFcn = createCallbackFcn(app, @floor2downButtonPushed, true);
            app.floor2down.Text = '↓';
            app.floor2imgl = uiimage(app.Floor2Panel);
            app.floor2imgl.ImageSource = '1.png';
            app.floor2imgr = uiimage(app.Floor2Panel);
            app.floor2imgr.ImageSource = '1.png';
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor1';
            app.floor1up = uibutton(app.Floor1Panel, 'push');
            app.floor1up.ButtonPushedFcn = createCallbackFcn(app, @floor1upButtonPushed, true);
            app.floor1up.Text = '↑';
            app.floor1imgl = uiimage(app.Floor1Panel);
            app.floor1imgl.ImageSource = '1.png';
            app.floor1imgr = uiimage(app.Floor1Panel);
            app.floor1imgr.ImageSource = '1.png';
            app.Floor3Panel_2 = uipanel(app.UIFigure);
            app.Floor3Panel_2.Title = 'Floor3';
            app.floor3down = uibutton(app.Floor3Panel_2, 'push');
            app.floor3down.ButtonPushedFcn = createCallbackFcn(app, @floor3downButtonPushed, true);
            app.floor3down.Text = '↓';
            app.floor3imgl = uiimage(app.Floor3Panel_2);
            app.floor3imgl.ImageSource = '1.png';
            app.floor3imgr = uiimage(app.Floor3Panel_2);
            app.floor3imgr.ImageSource = '1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Elevator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end