classdef InsidePanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panell        matlab.ui.container.Panel
        inside3l      matlab.ui.control.Button
        inside1l      matlab.ui.control.Button
        inside2l      matlab.ui.control.Button
        OpenButtonl   matlab.ui.control.Button
        CloseButtonl  matlab.ui.control.Button
        RingButtonl   matlab.ui.control.Button
        insideimgl    matlab.ui.control.Image
        Lamp          matlab.ui.control.Lamp
        elevimgl      matlab.ui.control.Image
    end

    
    properties (Access = public)
        dummy % Description
        request
        requestl
        outside
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.elevimgl.Position = [191 10 150 186];
            app.requestl(1)=0;
            app.requestl(2)=0;
            app.requestl(3)=0;
            app.Lamp.Color = [0 1 0];
%             app.request=app.dummy.app.request;
%             app.dummy.runelev;
            
        end

        % Button pushed function: inside1l
        function inside1lButtonPushed(app, event)
            app.dummy.app.requestl(1)=1;
            if app.dummy.app.flagrunning==0 && app.dummy.app.testflag==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: inside2l
        function inside2lButtonPushed(app, event)
            app.dummy.app.requestl(2)=1;
            if app.dummy.app.flagrunning==0 && app.dummy.app.testflag==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: inside3l
        function inside3lButtonPushed(app, event)
            app.dummy.app.requestl(3)=1;
            if app.dummy.app.flagrunning==0 && app.dummy.app.testflag==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: OpenButtonl
        function OpenButtonlPushed(app, event)
            if app.outside.flagrunning==0
                app.dummy.openoptionl;
            end
        end

        % Button pushed function: CloseButtonl
        function CloseButtonlPushed(app, event)
            app.dummy.closedoorl;
        end

        % Button pushed function: RingButtonl
        function RingButtonlPushed(app, event)
            if app.Lamp.Color == [0 1 0]
                 app.Lamp.Color = [1 0 0];
            else  
                app.Lamp.Color = [0 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panell = uipanel(app.UIFigure);
            app.Panell.Title = 'InsidePanel_left';
            app.inside3l = uibutton(app.Panell, 'push');
            app.inside3l.ButtonPushedFcn = createCallbackFcn(app, @inside3lButtonPushed, true);
            app.inside3l.Text = '3';
            app.inside1l = uibutton(app.Panell, 'push');
            app.inside1l.ButtonPushedFcn = createCallbackFcn(app, @inside1lButtonPushed, true);
            app.inside1l.Text = '1';
            app.inside2l = uibutton(app.Panell, 'push');
            app.inside2l.ButtonPushedFcn = createCallbackFcn(app, @inside2lButtonPushed, true);
            app.inside2l.Text = '2';
            app.OpenButtonl = uibutton(app.Panell, 'push');
            app.OpenButtonl.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonlPushed, true);
            app.OpenButtonl.Text = 'Open';
            app.CloseButtonl = uibutton(app.Panell, 'push');
            app.CloseButtonl.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonlPushed, true);
            app.CloseButtonl.Text = 'Close';
            app.RingButtonl = uibutton(app.Panell, 'push');
            app.RingButtonl.ButtonPushedFcn = createCallbackFcn(app, @RingButtonlPushed, true);
            app.RingButtonl.Text = 'Ring';
            app.insideimgl = uiimage(app.Panell);
            app.insideimgl.ImageSource = '1.png';
            app.Lamp = uilamp(app.Panell);
            app.elevimgl = uiimage(app.UIFigure);
            app.elevimgl.ImageSource = 'closed.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InsidePanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end