classdef InsidePanel2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panelr        matlab.ui.container.Panel
        inside3r      matlab.ui.control.Button
        inside1r      matlab.ui.control.Button
        inside2r      matlab.ui.control.Button
        OpenButtonr   matlab.ui.control.Button
        CloseButtonr  matlab.ui.control.Button
        RingButtonr   matlab.ui.control.Button
        insideimgr    matlab.ui.control.Image
        Lamp          matlab.ui.control.Lamp
        elevimgr      matlab.ui.control.Image
    end

    
    properties (Access = public)
        dummy
        requestr % Description
        outside
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.elevimgr.Position = [31 27 150 186];
            app.requestr(1)=0;
            app.requestr(2)=0;
            app.requestr(3)=0;
%             app.dummy.runelevr;
        end

        % Button pushed function: inside3r
        function inside3rButtonPushed(app, event)
            app.dummy.app.requestr(3)=1;
            if app.dummy.app.flagrunning==0 && app.dummy.app.testflag==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: inside2r
        function inside2rButtonPushed(app, event)
            app.dummy.app.requestr(2)=1;
            if app.dummy.app.flagrunning==0 && app.dummy.app.testflag==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: inside1r
        function inside1rButtonPushed(app, event)
            app.dummy.app.requestr(1)=1;
            if app.dummy.app.flagrunning==0 && app.dummy.app.testflag==1
                app.dummy.app.flagrunning=1;
                app.dummy.runelevs;
            end
        end

        % Button pushed function: OpenButtonr
        function OpenButtonrPushed(app, event)
            if app.outside.flagrunning==0
                app.dummy.openoptionr;
            end
        end

        % Button pushed function: CloseButtonr
        function CloseButtonrPushed(app, event)
            app.dummy.closedoorr;
        end

        % Button pushed function: RingButtonr
        function RingButtonrPushed(app, event)
            if app.Lamp.Color == [0 1 0]
                 app.Lamp.Color = [1 0 0];
            else  
                app.Lamp.Color = [0 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panelr = uipanel(app.UIFigure);
            app.Panelr.Title = 'InsidePanel_right';
            app.inside3r = uibutton(app.Panelr, 'push');
            app.inside3r.ButtonPushedFcn = createCallbackFcn(app, @inside3rButtonPushed, true);
            app.inside3r.Text = '3';
            app.inside1r = uibutton(app.Panelr, 'push');
            app.inside1r.ButtonPushedFcn = createCallbackFcn(app, @inside1rButtonPushed, true);
            app.inside1r.Text = '1';
            app.inside2r = uibutton(app.Panelr, 'push');
            app.inside2r.ButtonPushedFcn = createCallbackFcn(app, @inside2rButtonPushed, true);
            app.inside2r.Text = '2';
            app.OpenButtonr = uibutton(app.Panelr, 'push');
            app.OpenButtonr.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonrPushed, true);
            app.OpenButtonr.Text = 'Open';
            app.CloseButtonr = uibutton(app.Panelr, 'push');
            app.CloseButtonr.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonrPushed, true);
            app.CloseButtonr.Text = 'Close';
            app.RingButtonr = uibutton(app.Panelr, 'push');
            app.RingButtonr.ButtonPushedFcn = createCallbackFcn(app, @RingButtonrPushed, true);
            app.RingButtonr.Text = 'Ring';
            app.insideimgr = uiimage(app.Panelr);
            app.insideimgr.ImageSource = '1.png';
            app.Lamp = uilamp(app.Panelr);
            app.elevimgr = uiimage(app.UIFigure);
            app.elevimgr.ImageSource = 'closed.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InsidePanel2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end