classdef huangrd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        Button_10               matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        Button_9                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Button_13               matlab.ui.control.Button
        Button_14               matlab.ui.control.Button
        Button_12               matlab.ui.control.Button
        Button_15               matlab.ui.control.Button
        Button_16               matlab.ui.control.Button
        Button_17               matlab.ui.control.Button
        Button_18               matlab.ui.control.Button
        Button_19               matlab.ui.control.Button
        Button_20               matlab.ui.control.Button
        Button_21               matlab.ui.control.Button
        Button_22               matlab.ui.control.Button
        LeftButton              matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
        UpButton                matlab.ui.control.Button
        DownButton              matlab.ui.control.Button
        StepLabel               matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Level1Button            matlab.ui.control.Button
        Level2Button            matlab.ui.control.Button
        Level3Button            matlab.ui.control.Button
        Level4Button            matlab.ui.control.Button
        Level5Button            matlab.ui.control.Button
        StartyourowngameButton  matlab.ui.control.Button
        returnButton            matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        SUCCESSLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        controlSys % Description
        chess4_1
        chess4_2
        chess4_3
        chess4_4
        chess2_1
        chess2_2
        chess2_3
        chess2_4
        chess3
        chess1
        
    end
    
    
    methods (Access = public)
        
        function closeVisible(app)
            app.LeftButton.Visible="off";
            app.RightButton.Visible="off";
            app.UpButton.Visible="off";
            app.DownButton.Visible="off";
        end
        
  
        function check_success(app)
            if (app.Button.Position(1,2)<=2) &&(abs(app.Button.Position(1,1)-60)<=2)
                app.SUCCESSLabel.Text="Success!";
                app.SUCCESSLabel.Visible="On";
            end
            
        end  
        
        function resetPanel(app)
            app.Panel.Position(1,1)=201;
            app.Panel.Position(1,2)=91;
            app.Panel.Position(1,3)=242;
            app.Panel.Position(1,4)=302;
            
        end
        
        function resetBut(app)
            app.Button.Position=[61 181 120 120];
            app.Button_2.Position=[1 181 60 120];
            app.Button_3.Position=[181 181 60 120];
            app.Button_4.Position=[1 61 60 120];
            app.Button_5.Position=[181 61 60 120];
            app.Button_6.Position=[61 121 120 60];
            app.Button_7.Position=[1 1 60 60 ];
            app.Button_8.Position=[61 61 60 60];
            app.Button_9.Position=[121 61 60 60];
            app.Button_10.Position=[181 1 60 60];
        end
        
        
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            closeVisible(app);
            app.chess4_1.button4_movement();
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.controlSys.directionControl(3);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.controlSys.directionControl(4);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            closeVisible(app);
            app.chess4_4.button4_movement();
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            closeVisible(app);
            app.chess4_2.button4_movement();
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.controlSys.directionControl(1);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.controlSys.directionControl(2);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            closeVisible(app);
            app.chess4_3.button4_movement();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            closeVisible(app);
            app.chess1.button_movement();
            check_success(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            closeVisible(app);
            app.chess2_1.button2_moment();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            closeVisible(app);
            app.chess2_3.button2_moment();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            closeVisible(app);
            app.chess2_2.button2_moment();
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            closeVisible(app);
            app.chess2_4.button2_moment();
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            closeVisible(app);
            app.chess3.button3_moment();
        end

        % Button pushed function: Level1Button
        function Level1ButtonPushed(app, event)
            app.SUCCESSLabel.Visible="Off";
            app.Button_6.Position(1,1)=61;
            app.Button_6.Position(1,2)=241;
            app.Button.Position(1,1)=61;
            app.Button.Position(1,2)=121;
            app.Button_7.Position(1,1)=1;
            app.Button_7.Position(1,2)=181;
            app.Button_10.Position(1,1)=1;
            app.Button_10.Position(1,2)=121;
            app.Button_8.Position(1,1)=181;
            app.Button_8.Position(1,2)=181;
            app.Button_9.Position(1,1)=181;
            app.Button_9.Position(1,2)=121;
            app.Button_2.Position(1,1)=1;
            app.Button_2.Position(1,2)=1;
            app.Button_3.Position(1,1)=61;
            app.Button_3.Position(1,2)=1;
            app.Button_4.Position(1,1)=121;
            app.Button_4.Position(1,2)=1;
            app.Button_5.Position(1,1)=181;
            app.Button_5.Position(1,2)=1;
            app.controlSys.step=0;
            app.Label.Text=num2str(app.controlSys.step);
            app.controlSys.cal_blank();
        end

        % Button pushed function: Level2Button
        function Level2ButtonPushed(app, event)
            app.SUCCESSLabel.Visible="Off";
            app.Button_6.Position(1,1)=61;
            app.Button_6.Position(1,2)=121;
            app.Button.Position(1,1)=61;
            app.Button.Position(1,2)=181;
            app.Button_7.Position(1,1)=1;
            app.Button_7.Position(1,2)=241;
            app.Button_10.Position(1,1)=1;
            app.Button_10.Position(1,2)=181;
            app.Button_8.Position(1,1)=181;
            app.Button_8.Position(1,2)=241;
            app.Button_9.Position(1,1)=181;
            app.Button_9.Position(1,2)=181;
            app.Button_2.Position(1,1)=121;
            app.Button_2.Position(1,2)=1;
            app.Button_3.Position(1,1)=61;
            app.Button_3.Position(1,2)=1;
            app.Button_4.Position(1,1)=1;
            app.Button_4.Position(1,2)=61;
            app.Button_5.Position(1,1)=181;
            app.Button_5.Position(1,2)=61;
            app.controlSys.step=0;
            app.Label.Text=num2str(app.controlSys.step);
            app.controlSys.cal_blank();
        end

        % Button pushed function: Level3Button
        function Level3ButtonPushed(app, event)
            app.SUCCESSLabel.Visible="Off";
            app.Button_6.Position(1,1)=1;
            app.Button_6.Position(1,2)=1;
            app.Button.Position(1,1)=61;
            app.Button.Position(1,2)=61;
            app.Button_7.Position(1,1)=1;
            app.Button_7.Position(1,2)=61;
            app.Button_10.Position(1,1)=181;
            app.Button_10.Position(1,2)=61;
            app.Button_8.Position(1,1)=181;
            app.Button_8.Position(1,2)=1;
            app.Button_9.Position(1,1)=121;
            app.Button_9.Position(1,2)=1;
            app.Button_2.Position(1,1)=1;
            app.Button_2.Position(1,2)=181;
            app.Button_3.Position(1,1)=61;
            app.Button_3.Position(1,2)=181;
            app.Button_4.Position(1,1)=121;
            app.Button_4.Position(1,2)=181;
            app.Button_5.Position(1,1)=181;
            app.Button_5.Position(1,2)=181;
            app.controlSys.step=0;
            app.Label.Text=num2str(app.controlSys.step);
            app.controlSys.cal_blank();
        end

        % Button pushed function: Level4Button
        function Level4ButtonPushed(app, event)
            app.SUCCESSLabel.Visible="Off";
            app.Button_6.Position(1,1)=61;
            app.Button_6.Position(1,2)=1;
            app.Button.Position(1,1)=61;
            app.Button.Position(1,2)=181;
            app.Button_7.Position(1,1)=1;
            app.Button_7.Position(1,2)=241;
            app.Button_10.Position(1,1)=181;
            app.Button_10.Position(1,2)=241;
            app.Button_8.Position(1,1)=61;
            app.Button_8.Position(1,2)=61;
            app.Button_9.Position(1,1)=121;
            app.Button_9.Position(1,2)=61;
            app.Button_2.Position(1,1)=1;
            app.Button_2.Position(1,2)=121;
            app.Button_3.Position(1,1)=1;
            app.Button_3.Position(1,2)=1;
            app.Button_4.Position(1,1)=181;
            app.Button_4.Position(1,2)=1;
            app.Button_5.Position(1,1)=181;
            app.Button_5.Position(1,2)=121;
            app.controlSys.step=0;
            app.Label.Text=num2str(app.controlSys.step);
            app.controlSys.cal_blank();
        end

        % Button pushed function: Level5Button
        function Level5ButtonPushed(app, event)
            app.SUCCESSLabel.Visible="Off";
            app.Button_6.Position(1,1)=61;
            app.Button_6.Position(1,2)=61;
            app.Button.Position(1,1)=61;
            app.Button.Position(1,2)=181;
            app.Button_7.Position(1,1)=1;
            app.Button_7.Position(1,2)=121;
            app.Button_10.Position(1,1)=61;
            app.Button_10.Position(1,2)=121;
            app.Button_8.Position(1,1)=121;
            app.Button_8.Position(1,2)=121;
            app.Button_9.Position(1,1)=181;
            app.Button_9.Position(1,2)=121;
            app.Button_2.Position(1,1)=1;
            app.Button_2.Position(1,2)=181;
            app.Button_3.Position(1,1)=181;
            app.Button_3.Position(1,2)=181;
            app.Button_4.Position(1,1)=1;
            app.Button_4.Position(1,2)=1;
            app.Button_5.Position(1,1)=181;
            app.Button_5.Position(1,2)=1;
            app.controlSys.step=0;
            app.controlSys.cal_blank();
        end

        % Button pushed function: StartyourowngameButton
        function StartyourowngameButtonPushed(app, event)
            app.controlSys.step=0;
            app.controlSys.mode=1;
            app.controlSys.Block_type=0;
            app.controlSys.Block=app.Button;
            app.controlSys.have=[];
            app.controlSys.empty_block=[];
            app.controlSys.closePosition();
            app.controlSys.pos={app.Button,app.Button_2,app.Button_3,app.Button_4,app.Button_5,app.Button_6,app.Button_7,app.Button_8,app.Button_9,app.Button_10,app.Button_11};
            app.controlSys.posButton={app.Button_11,app.Button_12,app.Button_13,app.Button_14,app.Button_15,app.Button_16,app.Button_17,app.Button_18,app.Button_19,app.Button_20,app.Button_21,app.Button_22};
            % app.controlSys.seat=[1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20];
            app.Level1Button.Visible="off";
            app.Level2Button.Visible="off";
            app.Level3Button.Visible="off";
            app.Level4Button.Visible="off";
            app.Level5Button.Visible="off";
            
            
            app.Panel.Position(1,1)=100;
            app.Panel.Position(1,3)=500;
            app.Button.Visible="off";
            app.Button_6.Visible="off";
            app.Button_2.Visible="off";
            app.Button_3.Visible="off";
            app.Button_4.Visible="off";
            app.Button_5.Visible="off";
            app.Button_7.Visible="off";
            app.Button_10.Visible="off";
            app.Button_8.Visible="off";
            app.Button_9.Visible="off";
            app.SUCCESSLabel.Visible="off";
            closeVisible(app);
            app.returnButton.Visible="On";
            app.controlSys.choosePos();
            app.PlayButton.Visible="off";
            app.controlSys.step=0;
            app.Label.Text=num2str(app.controlSys.step);
            
        end

        % Button pushed function: returnButton
        function returnButtonPushed(app, event)
            app.controlSys.closePosition();
            resetPanel(app);
            resetBut(app);
            app.controlSys.step=0;
            app.Level1Button.Visible="on";
            app.Level2Button.Visible="on";
            app.Level3Button.Visible="on";
            app.Level4Button.Visible="on";
            app.Level5Button.Visible="on";
            app.Button.Visible="on";
            app.Button_6.Visible="on";
            app.Button_2.Visible="on";
            app.Button_3.Visible="on";
            app.Button_4.Visible="on";
            app.Button_5.Visible="on";
            app.Button_7.Visible="on";
            app.Button_10.Visible="on";
            app.Button_8.Visible="on";
            app.Button_9.Visible="on";
            app.SUCCESSLabel.Visible="on";
            app.SUCCESSLabel.Text='HuaRongDao!';
            app.PlayButton.Visible="off";
            app.returnButton.Visible="Off";
            app.controlSys.cal_blank();
            app.controlSys.step=0;
            app.Label.Text=num2str(app.controlSys.step);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
          app.controlSys.butPos(app.Button_11);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.controlSys.butPos(app.Button_12);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.controlSys.butPos(app.Button_13);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.controlSys.butPos(app.Button_14);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.controlSys.butPos(app.Button_15);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.controlSys.butPos(app.Button_16);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.controlSys.butPos(app.Button_17);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.controlSys.butPos(app.Button_18);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.controlSys.butPos(app.Button_19);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.controlSys.butPos(app.Button_20);
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            app.controlSys.butPos(app.Button_21);
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            app.controlSys.butPos(app.Button_22);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            resetPanel(app);
            app.controlSys.cal_blank();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '曹操';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '关羽';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '张飞';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '黄忠';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '马超';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '卒';
            app.Button_10 = uibutton(app.Panel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '卒';
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '卒';
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '卒';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '赵云';
            app.Button_11 = uibutton(app.Panel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Visible = 'off';
            app.Button_11.Text = '1';
            app.Button_13 = uibutton(app.Panel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Visible = 'off';
            app.Button_13.Text = '3';
            app.Button_14 = uibutton(app.Panel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Visible = 'off';
            app.Button_14.Text = '4';
            app.Button_12 = uibutton(app.Panel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Visible = 'off';
            app.Button_12.Text = '2';
            app.Button_15 = uibutton(app.Panel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Visible = 'off';
            app.Button_15.Text = '5';
            app.Button_16 = uibutton(app.Panel, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Visible = 'off';
            app.Button_16.Text = '6';
            app.Button_17 = uibutton(app.Panel, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Visible = 'off';
            app.Button_17.Text = '7';
            app.Button_18 = uibutton(app.Panel, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Visible = 'off';
            app.Button_18.Text = '8';
            app.Button_19 = uibutton(app.Panel, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Visible = 'off';
            app.Button_19.Text = '9';
            app.Button_20 = uibutton(app.Panel, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Visible = 'off';
            app.Button_20.Text = '10';
            app.Button_21 = uibutton(app.Panel, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Visible = 'off';
            app.Button_21.Text = '11';
            app.Button_22 = uibutton(app.Panel, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Visible = 'off';
            app.Button_22.Text = '12';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Visible = 'off';
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Visible = 'off';
            app.RightButton.Text = 'Right';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Visible = 'off';
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Visible = 'off';
            app.DownButton.Text = 'Down';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Level1Button = uibutton(app.UIFigure, 'push');
            app.Level1Button.ButtonPushedFcn = createCallbackFcn(app, @Level1ButtonPushed, true);
            app.Level1Button.Text = 'Level1';
            app.Level2Button = uibutton(app.UIFigure, 'push');
            app.Level2Button.ButtonPushedFcn = createCallbackFcn(app, @Level2ButtonPushed, true);
            app.Level2Button.Text = 'Level2';
            app.Level3Button = uibutton(app.UIFigure, 'push');
            app.Level3Button.ButtonPushedFcn = createCallbackFcn(app, @Level3ButtonPushed, true);
            app.Level3Button.Text = 'Level3';
            app.Level4Button = uibutton(app.UIFigure, 'push');
            app.Level4Button.ButtonPushedFcn = createCallbackFcn(app, @Level4ButtonPushed, true);
            app.Level4Button.Text = 'Level4';
            app.Level5Button = uibutton(app.UIFigure, 'push');
            app.Level5Button.ButtonPushedFcn = createCallbackFcn(app, @Level5ButtonPushed, true);
            app.Level5Button.Text = 'Level5';
            app.StartyourowngameButton = uibutton(app.UIFigure, 'push');
            app.StartyourowngameButton.ButtonPushedFcn = createCallbackFcn(app, @StartyourowngameButtonPushed, true);
            app.StartyourowngameButton.Text = 'Start your own game';
            app.returnButton = uibutton(app.UIFigure, 'push');
            app.returnButton.ButtonPushedFcn = createCallbackFcn(app, @returnButtonPushed, true);
            app.returnButton.Visible = 'off';
            app.returnButton.Text = 'return';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play!';
            app.SUCCESSLabel = uilabel(app.UIFigure);
            app.SUCCESSLabel.Visible = 'off';
            app.SUCCESSLabel.Text = '        SUCCESS!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = huangrd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end