classdef PatientUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BolusButton                matlab.ui.control.Button
        showhourlyinjectionButton  matlab.ui.control.Button
        showdailyinjectionButton   matlab.ui.control.Button
        MessagePanel               matlab.ui.container.Panel
        Message                    matlab.ui.control.Label
        showinjectiondataButton    matlab.ui.control.Button
        dismissButton              matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        OperationProcessor
    end
    
    methods (Access = public)
        
        function displayMessage(app,Message)
            app.Message.Text=Message;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            while true
                prompt = ["your name","your weight(kg)"];
                answer = inputdlg(prompt,"log in",[1 35]);
                %cancelled
                if isempty(answer)
                    delete(app);
                    return;
                end
                name = answer{1};
                app.OperationProcessor.pis.patient.Name = name;
                weight = str2num(answer{2});
                %right weight
                if ~isempty(weight)
                    weight = floor(weight/10)*10;
                    app.OperationProcessor.pis.patient.weight = weight;
                    break;
                %wrong weight
                else
                    uiwait(warndlg("wrong weight"));
                end
                
            %app.sf = timer('app',app);
            end
        end

        % Button pushed function: BolusButton
        function BolusButtonPushed(app, event)
            effect = app.OperationProcessor.decideButtonPressEffect();
            if effect
                app.OperationProcessor.pis.bolus = [app.OperationProcessor.pis.bolus; app.OperationProcessor.pis.currentbolus];
            end
        end

        % Button pushed function: showhourlyinjectionButton
        function showhourlyinjectionButtonPushed(app, event)
            app.OperationProcessor.displayMessage_patient(sprintf('Time is %s now',datetime));
            app.OperationProcessor.displayMessage_patient(sprintf('The amount of injection in the last hour: %.2f ml',app.OperationProcessor.pis.hour_in));
        end

        % Button pushed function: showdailyinjectionButton
        function showdailyinjectionButtonPushed(app, event)
            app.OperationProcessor.displayMessage_patient(sprintf('Time is %s now',datetime));
            app.OperationProcessor.displayMessage_patient(sprintf('The amount of injection in the last day: %.2f ml',app.OperationProcessor.pis.day_in));
        end

        % Button pushed function: dismissButton
        function dismissButtonPushed(app, event)
            app.Message.Text(1)=[];
        end

        % Button pushed function: showinjectiondataButton
        function showinjectiondataButtonPushed(app, event)
            app.OperationProcessor.displayMessage_patient(sprintf('Time is %s now',datetime));
            app.OperationProcessor.displayMessage_patient(sprintf('current baseline: %.2f ml/min',app.OperationProcessor.pis.currentbaseline));
            app.OperationProcessor.displayMessage_patient(sprintf('current bolus: %.2f ml',app.OperationProcessor.pis.currentbolus));
            app.OperationProcessor.displayMessage_patient(sprintf('current hourly limit is %.2f ml',app.OperationProcessor.pis.hourlylimit));
            app.OperationProcessor.displayMessage_patient(sprintf('current daily limit is %.2f ml',app.OperationProcessor.pis.dailylimit));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BolusButton = uibutton(app.UIFigure, 'push');
            app.BolusButton.ButtonPushedFcn = createCallbackFcn(app, @BolusButtonPushed, true);
            app.BolusButton.Text = 'Bolus';
            app.showhourlyinjectionButton = uibutton(app.UIFigure, 'push');
            app.showhourlyinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @showhourlyinjectionButtonPushed, true);
            app.showhourlyinjectionButton.Text = 'show hourly injection';
            app.showdailyinjectionButton = uibutton(app.UIFigure, 'push');
            app.showdailyinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @showdailyinjectionButtonPushed, true);
            app.showdailyinjectionButton.Text = 'show daily injection';
            app.MessagePanel = uipanel(app.UIFigure);
            app.MessagePanel.Title = 'Message';
            app.Message = uilabel(app.MessagePanel);
            app.Message.Text = ' ';
            app.showinjectiondataButton = uibutton(app.UIFigure, 'push');
            app.showinjectiondataButton.ButtonPushedFcn = createCallbackFcn(app, @showinjectiondataButtonPushed, true);
            app.showinjectiondataButton.Text = 'show injection data';
            app.dismissButton = uibutton(app.UIFigure, 'push');
            app.dismissButton.ButtonPushedFcn = createCallbackFcn(app, @dismissButtonPushed, true);
            app.dismissButton.Text = 'dismiss';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end