classdef PhysicianUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        showhourlyinjectionButton  matlab.ui.control.Button
        showdailyinjectionButton   matlab.ui.control.Button
        showinjectiondataButton    matlab.ui.control.Button
        MessagePanel               matlab.ui.container.Panel
        Message                    matlab.ui.control.Label
        dismissButton              matlab.ui.control.Button
        changeButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        OperationProcessor
    end
    
    methods (Access = public)
        
        function displayMessage(app,Message)
            app.Message.Text=Message;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           while true
                prompt = ["your name","your password"];
                answer = inputdlg(prompt,"log in",[1 35]);
                if isempty(answer)
                    delete(app);
                    return;
                end
                name = answer{1};
                password = answer{2};
                if strcmp(name,"myx") && strcmp(password,"1234")
                    break;
                end
            end
        end

        % Button pushed function: showhourlyinjectionButton
        function showhourlyinjectionButtonPushed(app, event)
            app.OperationProcessor.displayMessage_physician(sprintf('Time is %s now',datetime));
            app.OperationProcessor.displayMessage_physician(sprintf('The amount of injection in the last hour: %.2f ml',app.OperationProcessor.pis.hour_in));
        end

        % Button pushed function: showdailyinjectionButton
        function showdailyinjectionButtonPushed(app, event)
            app.OperationProcessor.displayMessage_physician(sprintf('Time is %s now',datetime));
            app.OperationProcessor.displayMessage_physician(sprintf('The amount of injection in the last day: %.2f ml',app.OperationProcessor.pis.day_in));
        end

        % Button pushed function: showinjectiondataButton
        function showinjectiondataButtonPushed(app, event)
            app.OperationProcessor.displayMessage_physician(sprintf('Time is %s now',datetime));
            app.OperationProcessor.displayMessage_physician(sprintf('current baseline: %.2f ml/min',app.OperationProcessor.pis.currentbaseline));
            app.OperationProcessor.displayMessage_physician(sprintf('current bolus: %.2f ml',app.OperationProcessor.pis.currentbolus));
            app.OperationProcessor.displayMessage_physician(sprintf('current hourly limit is %.2f ml',app.OperationProcessor.pis.hourlylimit));
            app.OperationProcessor.displayMessage_physician(sprintf('current daily limit is %.2f ml',app.OperationProcessor.pis.dailylimit));
        end

        % Button pushed function: dismissButton
        function dismissButtonPushed(app, event)
            app.Message.Text(1)=[];
        end

        % Button pushed function: changeButton
        function changeButtonPushed(app, event)
            changeapp = changeUI;
            changeapp.physicianapp = app;
            changeapp.baselineSlider.Value = app.OperationProcessor.pis.currentbaseline;
            changeapp.bolusSlider.Value = app.OperationProcessor.pis.currentbolus;
            changeapp.hourlylimitSlider.Value = app.OperationProcessor.pis.hourlylimit;
            changeapp.dailylimitSlider.Value = app.OperationProcessor.pis.dailylimit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.showhourlyinjectionButton = uibutton(app.UIFigure, 'push');
            app.showhourlyinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @showhourlyinjectionButtonPushed, true);
            app.showhourlyinjectionButton.Text = 'show hourly injection';
            app.showdailyinjectionButton = uibutton(app.UIFigure, 'push');
            app.showdailyinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @showdailyinjectionButtonPushed, true);
            app.showdailyinjectionButton.Text = 'show daily injection';
            app.showinjectiondataButton = uibutton(app.UIFigure, 'push');
            app.showinjectiondataButton.ButtonPushedFcn = createCallbackFcn(app, @showinjectiondataButtonPushed, true);
            app.showinjectiondataButton.Text = 'show injection data';
            app.MessagePanel = uipanel(app.UIFigure);
            app.MessagePanel.Title = 'Message';
            app.Message = uilabel(app.MessagePanel);
            app.Message.Text = ' ';
            app.dismissButton = uibutton(app.UIFigure, 'push');
            app.dismissButton.ButtonPushedFcn = createCallbackFcn(app, @dismissButtonPushed, true);
            app.dismissButton.Text = 'dismiss';
            app.changeButton = uibutton(app.UIFigure, 'push');
            app.changeButton.ButtonPushedFcn = createCallbackFcn(app, @changeButtonPushed, true);
            app.changeButton.Text = 'change';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhysicianUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end