classdef changeUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        changecurrentbaselineButton  matlab.ui.control.Button
        changecurrentbolusButton     matlab.ui.control.Button
        changehourlylimitButton      matlab.ui.control.Button
        changedailylimitButton       matlab.ui.control.Button
        baselineSliderLabel          matlab.ui.control.Label
        baselineSlider               matlab.ui.control.Slider
        bolusSliderLabel             matlab.ui.control.Label
        bolusSlider                  matlab.ui.control.Slider
        hourlylimitSliderLabel       matlab.ui.control.Label
        hourlylimitSlider            matlab.ui.control.Slider
        dailylimitSliderLabel        matlab.ui.control.Label
        dailylimitSlider             matlab.ui.control.Slider
    end

    
    properties (Access = public)
        physicianapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: changecurrentbaselineButton
        function changecurrentbaselineButtonPushed(app, event)
            in =inputdlg("Please input new current baseline:","ChangeApp");
            try 
                cb = str2num(in{1});
            catch
                return;
            end
            if cb > app.physicianapp.OperationProcessor.pis.baselineinterval
                answer = questdlg("Are you sure to set this amount?","baseline","Yes","No","No");
                if strcmp(answer,"Yes")
                   app.physicianapp.OperationProcessor.pis.currentbaseline = cb;
                else
                    return;
                end
            else
                app.physicianapp.OperationProcessor.pis.currentbaseline = cb;
            end
            if cb > 0.2
                cb = 0.2;
            end
            app.baselineSlider.Value = cb;
        end

        % Button pushed function: changecurrentbolusButton
        function changecurrentbolusButtonPushed(app, event)
            in =inputdlg("Please input new current bolus:","ChangeApp");
            try
                cb = str2num(in{1});
            catch
                return;
            end
            if cb > app.physicianapp.OperationProcessor.pis.bolusinterval
                answer = questdlg("Are you sure to set this amount?","bolus","Yes","No","No");
                if strcmp(answer,"Yes")
                    app.physicianapp.OperationProcessor.pis.currentbolus = cb;                
                else
                    return;
                end
            else
                app.physicianapp.OperationProcessor.pis.currentbolus = cb; 
            end
            if cb > 1
                cb = 1;
            end
            app.bolusSlider.Value = cb;

        end

        % Button pushed function: changehourlylimitButton
        function changehourlylimitButtonPushed(app, event)
            in = inputdlg("Please input new hourly limit:","ChangeApp");
            try
                cb = str2num(in{1});
            catch
                return;
            end
            app.physicianapp.OperationProcessor.pis.hourlylimit = cb;
            if cb > 2
                cb = 2;
            end
            app.hourlylimitSlider.Value = cb;

        end

        % Button pushed function: changedailylimitButton
        function changedailylimitButtonPushed(app, event)
            in = inputdlg("Please input new daily limit:","ChangeApp");
            try
                cb = str2num(in{1});
            catch
                return;
            end
            app.physicianapp.OperationProcessor.pis.dailylimit = cb;
            if cb> 5
                cb = 5;
            end
            app.dailylimitSlider.Value = cb;
        end

        % Value changed function: baselineSlider
        function baselineSliderValueChanged(app, event)
            value = app.baselineSlider.Value;
            if value > app.physicianapp.OperationProcessor.pis.baselineinterval
                answer = questdlg("Are you sure to set this amount?","baseline","Yes","No","No");
                if strcmp(answer,"Yes")
                   app.physicianapp.OperationProcessor.pis.currentbaseline = value;
                else
                   app.baselineSlider.Value = app.physicianapp.OperationProcessor.pis.currentbaseline;
                end
            else
                app.physicianapp.OperationProcessor.pis.currentbaseline = value;
            end
        end

        % Value changed function: bolusSlider
        function bolusSliderValueChanged(app, event)
            value = app.bolusSlider.Value;
            if value > app.physicianapp.OperationProcessor.pis.bolusinterval
                answer = questdlg("Are you sure to set this amount?","bolus","Yes","No","No");
                if strcmp(answer,"Yes")
                    app.physicianapp.OperationProcessor.pis.currentbolus = value;                
                else
                    app.baselineSlider.Value = app.physicianapp.OperationProcessor.pis.currentbolus;
                end
            else
                app.physicianapp.OperationProcessor.pis.currentbolus = value; 
            end
        end

        % Value changed function: hourlylimitSlider
        function hourlylimitSliderValueChanged(app, event)
            value = app.hourlylimitSlider.Value;
            app.physicianapp.OperationProcessor.pis.hourlylimit = value;
        end

        % Value changed function: dailylimitSlider
        function dailylimitSliderValueChanged(app, event)
            value = app.dailylimitSlider.Value;
            app.physicianapp.OperationProcessor.pis.dailylimit = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.changecurrentbaselineButton = uibutton(app.UIFigure, 'push');
            app.changecurrentbaselineButton.ButtonPushedFcn = createCallbackFcn(app, @changecurrentbaselineButtonPushed, true);
            app.changecurrentbaselineButton.Text = 'change current baseline';
            app.changecurrentbolusButton = uibutton(app.UIFigure, 'push');
            app.changecurrentbolusButton.ButtonPushedFcn = createCallbackFcn(app, @changecurrentbolusButtonPushed, true);
            app.changecurrentbolusButton.Text = 'change current bolus';
            app.changehourlylimitButton = uibutton(app.UIFigure, 'push');
            app.changehourlylimitButton.ButtonPushedFcn = createCallbackFcn(app, @changehourlylimitButtonPushed, true);
            app.changehourlylimitButton.Text = 'change hourly limit';
            app.changedailylimitButton = uibutton(app.UIFigure, 'push');
            app.changedailylimitButton.ButtonPushedFcn = createCallbackFcn(app, @changedailylimitButtonPushed, true);
            app.changedailylimitButton.Text = 'change daily limit';
            app.baselineSliderLabel = uilabel(app.UIFigure);
            app.baselineSliderLabel.Text = 'baseline';
            app.baselineSlider = uislider(app.UIFigure);
            app.baselineSlider.Limits = [0 0.2];
            app.baselineSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.bolusSliderLabel = uilabel(app.UIFigure);
            app.bolusSliderLabel.Text = 'bolus';
            app.bolusSlider = uislider(app.UIFigure);
            app.bolusSlider.Limits = [0 1];
            app.bolusSlider.ValueChangedFcn = createCallbackFcn(app, @bolusSliderValueChanged, true);
            app.hourlylimitSliderLabel = uilabel(app.UIFigure);
            app.hourlylimitSliderLabel.Text = 'hourly limit';
            app.hourlylimitSlider = uislider(app.UIFigure);
            app.hourlylimitSlider.Limits = [0 2];
            app.hourlylimitSlider.ValueChangedFcn = createCallbackFcn(app, @hourlylimitSliderValueChanged, true);
            app.dailylimitSliderLabel = uilabel(app.UIFigure);
            app.dailylimitSliderLabel.Text = 'daily limit';
            app.dailylimitSlider = uislider(app.UIFigure);
            app.dailylimitSlider.Limits = [0 5];
            app.dailylimitSlider.ValueChangedFcn = createCallbackFcn(app, @dailylimitSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = changeUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end